classdef GUI_valvulopatias < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Valvulopatia                 matlab.ui.Figure
        AbrirButton                  matlab.ui.control.Button
        GuardarButton                matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        UIAxes_1                     matlab.ui.control.UIAxes
        DeteccindeValvulopatasLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        Label1                       matlab.ui.control.Label
        EjecutarButton               matlab.ui.control.Button
        Label2                       matlab.ui.control.Label
    end

    
    properties (Access = public)
        Springer_B_matrix
        Springer_pi_vector
        Springer_total_obs_distribution
        springer_options
        filtered_data = 0
        parms
        signal_name
        path_file
        text_result
        PCG_resampled
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Load data and resample data
            app.Springer_B_matrix = load('Springer_B_matrix.mat').Springer_B_matrix;
            app.Springer_pi_vector = load('Springer_pi_vector.mat').Springer_pi_vector;
            app.Springer_total_obs_distribution = load('Springer_total_obs_distribution.mat').Springer_total_obs_distribution;
            app.springer_options = default_Springer_HSMM_options;
            app.parms = load('parms_cnn.mat').parms;
            
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            %% Load data and resample data
            [app.signal_name, app.path_file] = uigetfile({'*.wav'}, ...
                'File Selector');
            if ~app.signal_name
                return
            end
            
            [data, fs] = audioread(strcat(app.path_file, app.signal_name));
            
            if length(data) > 60*fs
                data = data(1:60*fs);
            end
            
            % resample to 1000 Hz
            PCG_resampled = resample(data, app.springer_options.audio_Fs, ...
                fs); % resample to springer_options.audio_Fs (1000 Hz)
            % filter the signal between 25 to 400 Hz
            base_signal = PCG_resampled * 0.5 / rms(PCG_resampled);
            PCG_resampled = butterworth_low_pass_filter(PCG_resampled, ...
                2, 400, app.springer_options.audio_Fs, false);
            PCG_resampled = butterworth_high_pass_filter(PCG_resampled, ...
                2, 25, app.springer_options.audio_Fs, false);
            % remove spikes
            PCG_resampled = schmidt_spike_removal(PCG_resampled, ...
                app.springer_options.audio_Fs);
            
            app.PCG_resampled = PCG_resampled;
            
            % wavelet
            data_w = PCG_resampled * 0.5 / rms(PCG_resampled);
            [c, l] = wavedec(data_w, 3, 'db1');
            b = wthresh(c, 's', 0.1);
            app.filtered_data = waverec(b, l, 'db1');
            app.filtered_data = app.filtered_data * 0.5 / rms(app.filtered_data);
   
            app.UIAxes_1.reset;
            plot(app.UIAxes_1, base_signal, 'b')
            hold(app.UIAxes_1, 'on' )
            plot(app.UIAxes_1, app.filtered_data, "Color", '#D95319')
            legend(app.UIAxes_1, 'Señal original', 'Señal filtrada', "Location", "best")
            app.UIAxes_1.Title.String = strcat('Pre-procesamiento ("',app.signal_name,'")');
            hold(app.UIAxes_1, 'off' )
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            signal_struct.filtered_data = app.filtered_data;
            signal_struct.text_result = app.text_result;
            signal_struct.signal_name = app.signal_name(1:end-4);
            %file_name = uiputfile('filtered_data.mat');
            aux = strcat(signal_struct.signal_name,'_',signal_struct.text_result,'.mat');
            %if file_name
            save(aux, 'signal_struct')
            %end

        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            %% Running runSpringerSegmentationAlgorithm.m to obtain the assigned_states
            [assigned_states,t1,norm_sig] = runSpringerSegmentationAlgorithm(app.PCG_resampled,... 
                            app.springer_options.audio_Fs,... 
                            app.Springer_B_matrix, app.Springer_pi_vector,...
                            app.Springer_total_obs_distribution, false);
                        
            app.UIAxes_1.reset;
            plot(app.UIAxes_1, t1, norm_sig,'k')
            hold(app.UIAxes_1, 'on' )
            plot(app.UIAxes_1, t1, assigned_states,'r--')
            legend(app.UIAxes_1, 'Registro de audio', 'Segmentos', "Location", "best")
            app.UIAxes_1.Title.String = strcat('Segmentación ("',app.signal_name,'")');
            hold(app.UIAxes_1, 'off' )
            
            % get states
            idx_states = get_states(assigned_states);
                        
            %% CNN
            app.parms.maxpooling = 2;
            
            N=60; sr = 1000; 
            Wn = 45*2/sr; 
            b1 = fir1(N, Wn, 'low', hamming(N+1));
            Wn = [45*2/sr, 80*2/sr];
            b2 = fir1(N, Wn, hamming(N+1));
            Wn = [80*2/sr, 200*2/sr];
            b3 = fir1(N, Wn, hamming(N+1));
            Wn = 200*2/sr;
            b4 = fir1(N, Wn, 'high', hamming(N+1));
            
            % filter signal in 4 different frequency bands,
            % [0,45],[45-80],[80-200],[200-400]
            clear PCG
            PCG(:,1) = filtfilt(b1, 1, app.PCG_resampled);
            PCG(:,2) = filtfilt(b2, 1, app.PCG_resampled);
            PCG(:,3) = filtfilt(b3, 1, app.PCG_resampled);
            PCG(:,4) = filtfilt(b4, 1, app.PCG_resampled);
            
            nfb = 4;
            nsamp = 2500;
            ncc = size(idx_states, 1)-1;
            X = nan(ncc, nsamp, nfb);
            for row=1:ncc
                for fb=1:nfb
                    tmp = PCG(idx_states(row,1):idx_states(row+1,1),fb);
                    N = nsamp-length(tmp);
                    % append zeros at the end of cardiac cycle
                    X(row,:,fb) = [tmp; zeros(N,1)];
                end
            end
            
            % run cnn feedforward
            res = nan(size(X,1), 2);
            for sample = 1:size(X,1)  
                s = squeeze(X(sample,:,:));
                res(sample,:) = feed_forward_cnn(s,app.parms);
            end
            prb_cnn = mean(res(:,2));
            
            if (prb_cnn > 0.4)
                %classifyResult = 1;
                app.text_result = 'anómalo';
            else
                %classifyResult = -1;
                app.text_result = 'normal';
            end
            % classifyResult: integer value where
            %                     1 = abnormal recording
            %                    -1 = normal recording
            %                     0 = unsure (too noisy)
            app.Label2.Text = strcat('El registro de audio');
            app.Label1.Text = strcat('"',app.signal_name,'" es');
            app.Label.Text = app.text_result;
            %classifyResult
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Valvulopatia = uifigure('Visible', 'off');
            app.Valvulopatia.Name = 'Valvulopatía';
            app.AbrirButton = uibutton(app.Valvulopatia, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.GuardarButton = uibutton(app.Valvulopatia, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Label = uilabel(app.Valvulopatia);
            app.Label.Text = '';
            app.UIAxes_1 = uiaxes(app.Valvulopatia);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.DeteccindeValvulopatasLabel = uilabel(app.Valvulopatia);
            app.DeteccindeValvulopatasLabel.Text = 'Detección de Valvulopatías';
            app.Image = uiimage(app.Valvulopatia);
            app.Image.ImageSource = 'espe.png';
            app.Label1 = uilabel(app.Valvulopatia);
            app.Label1.Text = '';
            app.EjecutarButton = uibutton(app.Valvulopatia, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.Label2 = uilabel(app.Valvulopatia);
            app.Label2.Text = '';
            % Show the figure after all components are created
            app.Valvulopatia.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_valvulopatias

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Valvulopatia)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Valvulopatia)
        end
    end
end